classdef Cockpit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_tab                   matlab.ui.Figure
        Engine_panel               matlab.ui.container.Panel
        EngineTempGauge            matlab.ui.control.Gauge
        EnginetempLabel            matlab.ui.control.Label
        EngineRPMGauge             matlab.ui.control.Gauge
        EngineRPMGaugeLabel        matlab.ui.control.Label
        Electric_system_panel      matlab.ui.container.Panel
        LowvoltageLamp             matlab.ui.control.Lamp
        LowvoltageLampLabel        matlab.ui.control.Label
        MainbattvoltageVGauge      matlab.ui.control.SemicircularGauge
        MainbattvoltageVLabel      matlab.ui.control.Label
        Alerting_panel             matlab.ui.container.Panel
        Main_DB                    matlab.ui.control.TextArea
        DialogboxTextAreaLabel     matlab.ui.control.Label
        Virt_cockpit_status_panel  matlab.ui.container.Panel
        UpdateButton               matlab.ui.control.Button
        FlightTrack_switch         matlab.ui.control.Switch
        DatastreamLabel_3          matlab.ui.control.Label
        Cockpit_switch             matlab.ui.control.Switch
        DatastreamLabel_2          matlab.ui.control.Label
        Data_stream_switch         matlab.ui.control.Switch
        DatastreamLabel            matlab.ui.control.Label
        WorldmapButton             matlab.ui.control.Button
        FlightattitudepanelPanel   matlab.ui.container.Panel
        Turn_coordinator           Aero.ui.control.TurnCoordinator
        Climb_indicator            Aero.ui.control.ClimbIndicator
        Heading_indicator          Aero.ui.control.HeadingIndicator
        Airspeed_indicator         Aero.ui.control.AirspeedIndicator
        Altimeter                  Aero.ui.control.Altimeter
        Artificial_horizon         Aero.ui.control.ArtificialHorizon
        Serial_port_Panel          matlab.ui.container.Panel
        Buffer_flush_button        matlab.ui.control.Button
        Connect_button             matlab.ui.control.Button
        Port_list                  matlab.ui.control.DropDown
        SelectportDropDownLabel    matlab.ui.control.Label
        ConnectedLamp              matlab.ui.control.Lamp
        ConnectedLampLabel         matlab.ui.control.Label
        available_ports_butt       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Serial_port % Description
        RadioLink % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
         app.Data_stream_switch.Enable = 0;      
         app.Connect_button.Enable = 0;
         app.Buffer_flush_button.Enable = 0;         
        end

        % Button pushed function: available_ports_butt
        function available_ports_buttPushed(app, event)
         available_ports = serialportlist("available");
         if isempty(available_ports)
          app.Main_DB.Value = "There are no available serial ports";
          app.Connect_button.Enable = 0;
         else
          app.Main_DB.Value = "There are available ports, chose one";            
          app.Port_list.Items = serialportlist("available");
          app.Connect_button.Enable = 1; % Enable connection button if at least an available port is present
         end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
         app.Artificial_horizon.Pitch = 10;
         app.Artificial_horizon.Roll = 10;
         app.Airspeed_indicator.Value = 150;
         app.Altimeter.Value = 3000;
         app.Climb_indicator.ClimbRate = -500;
         app.Heading_indicator.Value = 250;
         app.Turn_coordinator.Turn = 3;
         app.Turn_coordinator.Slip = 3;          
        end

        % Value changed function: Data_stream_switch
        function Data_stream_switchValueChanged(app, event)

         % Received string parsing   
         while(app.Data_stream_switch.Value == "On") % Loop executed every 0.1 second
          RadioLinkString = readline(app.RadioLink);
          % IT COULD BE GOOD TO INSERT A CHECK RELATIVE TO THE STRING CELL
          % NUMBER
          if (any(isletter(RadioLinkString)) == 0)      % This check is imolemented in order to filter potential wrong messages, a message from the UART must contains only numbers and spaces
           RadioLinkVector = str2num(RadioLinkString);  % Receivedstring character to numeric vector conversion
           RLV_Type_ID = RadioLinkVector(1);            % The first vector cell contains the vector type ID which identifies the content of the received string (attitude data, engine data etc.)
           
           if (app.Cockpit_switch.Value == "On")
           % String sorting
           switch RLV_Type_ID

            case 1 % Flight attitude panel strings
             app.Airspeed_indicator.Value = RadioLinkVector(2);
             app.Artificial_horizon.Roll = RadioLinkVector(3);
             app.Artificial_horizon.Pitch = RadioLinkVector(4);
             app.Altimeter.Value = RadioLinkVector(5);
             app.Climb_indicator.Value = RadioLinkVector(6);
             app.Heading_indicator.Value = RadioLinkVector(7);
             app.Turn_coordinator.Turn = RadioLinkVector(8);
             app.Turn_coordinator.Slip = RadioLinkVector(9);
           
            case 2 % Electric system panel strings
             app.MainbattvoltageVGauge.Value = RadioLinkVector(2)/100;

             if RadioLinkVector(2) < 760 % This value is considered low voltage
              app.LowvoltageLamp.Color = "red"; % LowvoltageLamp ON with low voltage
             else
              app.LowvoltageLamp.Color = "white"; 
             end

            case 3 % Engine panel strings
             app.EngineRPMGauge.Value = RadioLinkVector(2);
             app.EngineTempGauge.Value = RadioLinkVector(3);

            case 4 % Location panel strings
             if(app.WorldmapButton.Enable == 0 && app.FlightTrack_switch.Value == "On")
               cla % This command delete the last position on the location panel prior to plot the updated one
               geoplot(RadioLinkVector(2), RadioLinkVector(3),'o',"MarkerSize",6,"Color",'r'); % UAV position plotting on the location Panel
             end

            otherwise % Not correct strings
            end     
           end
          end
         %  pause(0.1);
         end
        end

        % Button pushed function: Connect_button
        function Connect_buttonButtonPushed(app, event)
          app.Serial_port = app.Port_list.Value; % Adding port number to the "serial port number" property
          app.RadioLink = serialport(app.Serial_port, 9600); % Creating the serial port object
          %configureTerminator(app.RadioLink,"CR/LF"); % Configuring the terminator for the incoming string
          if isempty(app.RadioLink)
           app.ConnectedLamp.Color = "white"; % ConnectionLamp ON whe RadioLink connected
          else
           app.ConnectedLamp.Color = "green"; 
           app.Data_stream_switch.Enable = 1;
           app.Buffer_flush_button.Enable = 1;
          end
        end

        % Button pushed function: Buffer_flush_button
        function Buffer_flush_buttonButtonPushed(app, event)
         % The flushing of the serial port must be used to ensure reading
         % as mush as possible real time data instead of old data still
         % present in the serial port buffer
         flush(app.RadioLink); 
        end

        % Button pushed function: WorldmapButton
        function WorldmapButtonPushed(app, event)
         app.WorldmapButton.Enable = 0;
         figure('Name','Flight map');
         geobasemap streets;
         geoplot(0,0,"Marker",'o',"MarkerSize",6,"Color",'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_tab = uifigure('Visible', 'off');
            app.Main_tab.Name = 'MATLAB App';
            app.Serial_port_Panel = uipanel(app.Main_tab);
            app.Serial_port_Panel.Title = 'Serial port panel';
            app.available_ports_butt = uibutton(app.Serial_port_Panel, 'push');
            app.available_ports_butt.ButtonPushedFcn = createCallbackFcn(app, @available_ports_buttPushed, true);
            app.available_ports_butt.Text = 'Find available port';
            app.ConnectedLampLabel = uilabel(app.Serial_port_Panel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.Serial_port_Panel);
            app.SelectportDropDownLabel = uilabel(app.Serial_port_Panel);
            app.SelectportDropDownLabel.Text = 'Select port';
            app.Port_list = uidropdown(app.Serial_port_Panel);
            app.Port_list.Items = {};
            app.Port_list.Value = {};
            app.Connect_button = uibutton(app.Serial_port_Panel, 'push');
            app.Connect_button.ButtonPushedFcn = createCallbackFcn(app, @Connect_buttonButtonPushed, true);
            app.Connect_button.Text = 'Connect';
            app.Buffer_flush_button = uibutton(app.Serial_port_Panel, 'push');
            app.Buffer_flush_button.ButtonPushedFcn = createCallbackFcn(app, @Buffer_flush_buttonButtonPushed, true);
            app.Buffer_flush_button.Text = 'Flush serial buffer';
            app.FlightattitudepanelPanel = uipanel(app.Main_tab);
            app.FlightattitudepanelPanel.Title = 'Flight attitude panel';
            app.Artificial_horizon = uiaerohorizon(app.FlightattitudepanelPanel);
            app.Altimeter = uiaeroaltimeter(app.FlightattitudepanelPanel);
            app.Airspeed_indicator = uiaeroairspeed(app.FlightattitudepanelPanel);
            app.Heading_indicator = uiaeroheading(app.FlightattitudepanelPanel);
            app.Climb_indicator = uiaeroclimb(app.FlightattitudepanelPanel);
            app.Turn_coordinator = uiaeroturn(app.FlightattitudepanelPanel);
            app.Virt_cockpit_status_panel = uipanel(app.Main_tab);
            app.Virt_cockpit_status_panel.Title = 'Cockpit control panel';
            app.WorldmapButton = uibutton(app.Virt_cockpit_status_panel, 'push');
            app.WorldmapButton.ButtonPushedFcn = createCallbackFcn(app, @WorldmapButtonPushed, true);
            app.WorldmapButton.Text = 'World map';
            app.DatastreamLabel = uilabel(app.Virt_cockpit_status_panel);
            app.DatastreamLabel.Text = 'Data stream';
            app.Data_stream_switch = uiswitch(app.Virt_cockpit_status_panel, 'slider');
            app.Data_stream_switch.ValueChangedFcn = createCallbackFcn(app, @Data_stream_switchValueChanged, true);
            app.DatastreamLabel_2 = uilabel(app.Virt_cockpit_status_panel);
            app.DatastreamLabel_2.Text = 'Flight ins. panel';
            app.Cockpit_switch = uiswitch(app.Virt_cockpit_status_panel, 'slider');
            app.DatastreamLabel_3 = uilabel(app.Virt_cockpit_status_panel);
            app.DatastreamLabel_3.Text = 'Live flight tracking';
            app.FlightTrack_switch = uiswitch(app.Virt_cockpit_status_panel, 'slider');
            app.UpdateButton = uibutton(app.Virt_cockpit_status_panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Alerting_panel = uipanel(app.Main_tab);
            app.Alerting_panel.Title = 'Alerting panel';
            app.DialogboxTextAreaLabel = uilabel(app.Alerting_panel);
            app.DialogboxTextAreaLabel.Text = 'Dialog box';
            app.Main_DB = uitextarea(app.Alerting_panel);
            app.Electric_system_panel = uipanel(app.Main_tab);
            app.Electric_system_panel.Title = 'Electric system panel';
            app.MainbattvoltageVLabel = uilabel(app.Electric_system_panel);
            app.MainbattvoltageVLabel.Text = 'Main batt. voltage [V]';
            app.MainbattvoltageVGauge = uigauge(app.Electric_system_panel, 'semicircular');
            app.MainbattvoltageVGauge.Limits = [0 20];
            app.LowvoltageLampLabel = uilabel(app.Electric_system_panel);
            app.LowvoltageLampLabel.Text = 'Low voltage';
            app.LowvoltageLamp = uilamp(app.Electric_system_panel);
            app.Engine_panel = uipanel(app.Main_tab);
            app.Engine_panel.Title = 'Engine panel';
            app.EngineRPMGaugeLabel = uilabel(app.Engine_panel);
            app.EngineRPMGaugeLabel.Text = 'Engine RPM';
            app.EngineRPMGauge = uigauge(app.Engine_panel, 'circular');
            app.EngineRPMGauge.Limits = [0 10000];
            app.EnginetempLabel = uilabel(app.Engine_panel);
            app.EnginetempLabel.Text = 'Engine temp. [°C]';
            app.EngineTempGauge = uigauge(app.Engine_panel, 'circular');
            app.EngineTempGauge.Limits = [0 150];
            % Show the figure after all components are created
            app.Main_tab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cockpit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_tab)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_tab)
        end
    end
end