classdef Cockpit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WorldmapButton               matlab.ui.control.Button
        FlightTrack_switch           matlab.ui.control.Switch
        DatastreamLabel_3            matlab.ui.control.Label
        Cockpit_switch               matlab.ui.control.Switch
        DatastreamLabel_2            matlab.ui.control.Label
        Data_stream_switch           matlab.ui.control.Switch
        DatastreamLabel              matlab.ui.control.Label
        UpdatateButton_2             matlab.ui.control.Button
        FlightinstrumentspanelPanel  matlab.ui.container.Panel
        Turn_coordinator             Aero.ui.control.TurnCoordinator
        Climb_indicator              Aero.ui.control.ClimbIndicator
        Heading_indicator            Aero.ui.control.HeadingIndicator
        Airspeed_indicator           Aero.ui.control.AirspeedIndicator
        Altimeter                    Aero.ui.control.Altimeter
        Artificial_horizon           Aero.ui.control.ArtificialHorizon
        Serial_port_Panel            matlab.ui.container.Panel
        Buffer_flush_button          matlab.ui.control.Button
        Connect_button               matlab.ui.control.Button
        Port_list                    matlab.ui.control.DropDown
        SelectportDropDownLabel      matlab.ui.control.Label
        ConnectedLamp                matlab.ui.control.Lamp
        ConnectedLampLabel           matlab.ui.control.Label
        available_ports_butt         matlab.ui.control.Button
        Main_DB                      matlab.ui.control.TextArea
        DialogboxTextAreaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Serial_port % Description
        RadioLink % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
         app.Data_stream_switch.Enable = 0;      
         app.Connect_button.Enable = 0;
         app.Buffer_flush_button.Enable = 0;         
        end

        % Button pushed function: available_ports_butt
        function available_ports_buttPushed(app, event)
         available_ports = serialportlist("available");
         if isempty(available_ports)
          app.Main_DB.Value = "There are no available serial ports";
          app.Connect_button.Enable = 0;
         else
          app.Main_DB.Value = "There are available ports, chose one";            
          app.Port_list.Items = serialportlist("available");
          app.Connect_button.Enable = 1; % Enable connection button if at least an available port is present
         end
        end

        % Button pushed function: UpdatateButton_2
        function UpdatateButton_2Pushed(app, event)
            app.Artificial_horizon.Pitch = 10;
            app.Artificial_horizon.Roll = 10;
            app.Airspeed_indicator.Value = 150;
            app.Altimeter.Value = 3000;
            app.Climb_indicator.ClimbRate = -500;
            app.Heading_indicator.Value = 250;
            app.Turn_coordinator.Turn = 3;
            app.Turn_coordinator.Slip = 3;          
        end

        % Value changed function: Data_stream_switch
        function Data_stream_switchValueChanged(app, event)

         % Received strign parsing   
         while(app.Data_stream_switch.Value == "On") % Loop executed every 0.1 second
          RadioLinkString = readline(app.RadioLink);
          RadioLinkVector = str2num(RadioLinkString); % Received string character to numeric vector conversion 
          RLV_Type_ID = RadioLinkVector(1); % The first vector cell contains the vector type ID which identifies the content of the received string (attitude data, engine data etc.)
         
          
           % String sorting
           switch RLV_Type_ID

            % Cockpit panel strings
            case 1 
             if (app.Cockpit_switch.Value == "On")
             app.Airspeed_indicator.Value = RadioLinkVector(2);
             end
             % %Extraction of the flight data to show on the "Flight instrument panel"
             % Airspeed = RadioLinkVector(2);
             % Roll = RadioLinkVector(3);
             % Pitch = RadioLinkVector(4);
             % GNSS_Altitude_SL = RadioLinkVector(5); % Sea level altitude from GNSS 
             % VertSpeed = RadioLinkVector(6);
            % Power system strings
            case 2
             % app.Altimeter.Value = RadioLinkVector(2);

            % Location panel string
               case 3 
                if(app.WorldmapButton.Enable == 0 && app.FlightTrack_switch.Value == "On")
                  cla
                    geoplot(RadioLinkVector(2), RadioLinkVector(3),'o',"MarkerSize",6,"Color",'r');
                                 end

            % Not correct strings
            otherwise
           end     
         
           pause(0.1);
         end
        end

        % Button pushed function: Connect_button
        function Connect_buttonButtonPushed(app, event)
          app.Serial_port = app.Port_list.Value; % Adding port number to the "serial port number" property
          app.RadioLink = serialport(app.Serial_port, 9600) % Creating the serial port object
          if isempty(app.RadioLink)
           app.ConnectedLamp.Color = "grey"; % ConnectionLamp ON whe RadioLink connected
          else
           app.ConnectedLamp.Color = "green"; 
           app.Data_stream_switch.Enable = 1;
           app.Buffer_flush_button.Enable = 1;
          end
        end

        % Button pushed function: Buffer_flush_button
        function Buffer_flush_buttonButtonPushed(app, event)
         % The flushing of the serial port must be used to ensure reading
         % as mush as possible real time data instead of old data still
         % present in the serial port buffer
         flush(app.RadioLink); 
        end

        % Button pushed function: WorldmapButton
        function WorldmapButtonPushed(app, event)
         app.WorldmapButton.Enable = 0;
         figure('Name','Flight map');
         geobasemap streets;
         geoplot(0,0,"Marker",'o',"MarkerSize",6,"Color",'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DialogboxTextAreaLabel = uilabel(app.UIFigure);
            app.DialogboxTextAreaLabel.Text = 'Dialog box';
            app.Main_DB = uitextarea(app.UIFigure);
            app.Serial_port_Panel = uipanel(app.UIFigure);
            app.Serial_port_Panel.Title = 'Serial port panel';
            app.available_ports_butt = uibutton(app.Serial_port_Panel, 'push');
            app.available_ports_butt.ButtonPushedFcn = createCallbackFcn(app, @available_ports_buttPushed, true);
            app.available_ports_butt.Text = 'Find available port';
            app.ConnectedLampLabel = uilabel(app.Serial_port_Panel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.Serial_port_Panel);
            app.SelectportDropDownLabel = uilabel(app.Serial_port_Panel);
            app.SelectportDropDownLabel.Text = 'Select port';
            app.Port_list = uidropdown(app.Serial_port_Panel);
            app.Port_list.Items = {};
            app.Port_list.Value = {};
            app.Connect_button = uibutton(app.Serial_port_Panel, 'push');
            app.Connect_button.ButtonPushedFcn = createCallbackFcn(app, @Connect_buttonButtonPushed, true);
            app.Connect_button.Text = 'Connect';
            app.Buffer_flush_button = uibutton(app.Serial_port_Panel, 'push');
            app.Buffer_flush_button.ButtonPushedFcn = createCallbackFcn(app, @Buffer_flush_buttonButtonPushed, true);
            app.Buffer_flush_button.Text = 'Flush serial buffer';
            app.FlightinstrumentspanelPanel = uipanel(app.UIFigure);
            app.FlightinstrumentspanelPanel.Title = 'Flight instruments panel';
            app.Artificial_horizon = uiaerohorizon(app.FlightinstrumentspanelPanel);
            app.Altimeter = uiaeroaltimeter(app.FlightinstrumentspanelPanel);
            app.Airspeed_indicator = uiaeroairspeed(app.FlightinstrumentspanelPanel);
            app.Heading_indicator = uiaeroheading(app.FlightinstrumentspanelPanel);
            app.Climb_indicator = uiaeroclimb(app.FlightinstrumentspanelPanel);
            app.Turn_coordinator = uiaeroturn(app.FlightinstrumentspanelPanel);
            app.UpdatateButton_2 = uibutton(app.UIFigure, 'push');
            app.UpdatateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatateButton_2Pushed, true);
            app.UpdatateButton_2.Text = 'Updatate';
            app.DatastreamLabel = uilabel(app.UIFigure);
            app.DatastreamLabel.Text = 'Data stream';
            app.Data_stream_switch = uiswitch(app.UIFigure, 'slider');
            app.Data_stream_switch.ValueChangedFcn = createCallbackFcn(app, @Data_stream_switchValueChanged, true);
            app.DatastreamLabel_2 = uilabel(app.UIFigure);
            app.DatastreamLabel_2.Text = 'Cockpit panel';
            app.Cockpit_switch = uiswitch(app.UIFigure, 'slider');
            app.DatastreamLabel_3 = uilabel(app.UIFigure);
            app.DatastreamLabel_3.Text = 'Flight tracking';
            app.FlightTrack_switch = uiswitch(app.UIFigure, 'slider');
            app.WorldmapButton = uibutton(app.UIFigure, 'push');
            app.WorldmapButton.ButtonPushedFcn = createCallbackFcn(app, @WorldmapButtonPushed, true);
            app.WorldmapButton.Text = 'World map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cockpit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end