classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainTab                      matlab.ui.Figure
        Main                         matlab.ui.container.Panel
        Gen_Mix_prof                 matlab.ui.control.Button
        Open_Plugin                  matlab.ui.control.Button
        Load_folder                  matlab.ui.control.Button
        ApplicationinformationPanel  matlab.ui.container.Panel
        GNSS_file                    matlab.ui.control.CheckBox
        ERR_file                     matlab.ui.control.CheckBox
        IMU_file                     matlab.ui.control.CheckBox
        Sys_file                     matlab.ui.control.CheckBox
        Label_1                      matlab.ui.control.Label
        FT_DB                        matlab.ui.control.TextArea
        MaximumspeedLabel_3          matlab.ui.control.Label
        MaxAlt_DB                    matlab.ui.control.TextArea
        MaximumspeedLabel_2          matlab.ui.control.Label
        MaxSpeed_DB                  matlab.ui.control.TextArea
        MaximumspeedLabel            matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        DialogBox                    matlab.ui.control.TextArea
        DialogboxTextAreaLabel       matlab.ui.control.Label
        FlighttrackplotPanel         matlab.ui.container.Panel
        MB_list                      matlab.ui.control.ListBox
        MapbaseListBoxLabel          matlab.ui.control.Label
        Print_globe                  matlab.ui.control.Button
        Print_map                    matlab.ui.control.Button
        MissionprofileanalyzerPanel  matlab.ui.container.Panel
        PlottimerangeSwitchLabel     matlab.ui.control.Label
        PlottimerangeSwitch          matlab.ui.control.Switch
        Print_Alt_Ftime              matlab.ui.control.Button
        Print_Batt_Ftime             matlab.ui.control.Button
        CustomtimerangePanel         matlab.ui.container.Panel
        EndTime_DB                   matlab.ui.control.TextArea
        EndtimeLabel                 matlab.ui.control.Label
        StarttimeLabel               matlab.ui.control.Label
        StartTime_DB                 matlab.ui.control.TextArea
        EndtimeinsecondsLabel        matlab.ui.control.Label
        EndTime_Slider               matlab.ui.control.Slider
        StarttimeinsecondsLabel      matlab.ui.control.Label
        StartTime_Slider             matlab.ui.control.Slider
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        wrk_path % This is the path where al the data files are stored
    end
    
    properties (Access = private)
        TimeRange_type % This proprerty is used to select the analysis time range, full means that the entire files are analized while custom allow you to select a time range and analyze it only
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
         app.GNSS_file.Value = 0;
         app.ERR_file.Value = 0;
         app.IMU_file.Value = 0;
         app.Sys_file.Value = 0;
         app.MB_list.Enable = 0;     
         app.Print_map.Enable = 0;
         app.Print_globe.Enable = 0;
         app.MaxSpeed_DB.Value = "0";
         app.MaxAlt_DB.Value = "0";
         app.FT_DB.Value = "0";
         app.Print_Batt_Ftime.Enable = 0;
         app.Print_Alt_Ftime.Enable = 0;  
         app.CustomtimerangePanel.Enable = 0;          
         app.DialogBox.Value = "Select the folder containing the data files," + ...
          " be sure to have a good internet connection" + ...
          " to download the maps bases";

         % Flag list to check the presence of the files into the selected
         % folder
         GNSS_data_exi = 0; 
         IMU_data_exi = 0; 
         Sys_data_exi = 0;
         ERR_data_exi = 0;
        end

        % Button pushed function: Load_folder
        function Load_folderPushed(app, event)
         app.wrk_path = uigetdir();
   
         % Checking the presence if the files in the folder
         GNSS_data_exi = (exist(fullfile(app.wrk_path, 'GNSS_data.txt'), 'file') == 2);
         IMU_data_exi = (exist(fullfile(app.wrk_path, 'IMU_data.txt'), 'file') == 2);
         Sys_data_exi = (exist(fullfile(app.wrk_path, 'Sys_data.txt'), 'file') == 2);
         ERR_data_exi = (exist(fullfile(app.wrk_path, 'ERR_data.txt'), 'file') == 2);

         app.GNSS_file.Value = GNSS_data_exi;
        
         app.IMU_file.Value = IMU_data_exi;
         app.ERR_file.Value = ERR_data_exi;
         app.Sys_file.Value = Sys_data_exi;
    
         if GNSS_data_exi == 1
          app.DialogBox.Value = "Use the two <Print Buttons> to show the recorded" + ...
          " track on the map or on the globe, " + ...
          " use the reletive selection box to chose " + ...
          " the map base"; 
          % Enable to plot the map or the globe
          app.Print_map.Enable = 1;
          app.Print_globe.Enable = 1;

          GNSS_data = importdata(fullfile(app.wrk_path, 'GNSS_data.txt'));
          GNSS_lat = GNSS_data(:,4);
          GNSS_lon = GNSS_data(:,5);
          GNSS_alt = GNSS_data(:,6);
          GNSS_speed = GNSS_data(:,7);

          % Extraction of the "max" data

          MaxSpeed = max(GNSS_speed); % Finding the maximum speed into the relative vector
          app.MaxSpeed_DB.Value = MaxSpeed + " s";

          MaxAlt = max(GNSS_alt); % Finding the maximum altitude into the relative vector
          app.MaxAlt_DB.Value = MaxAlt + " m";
         end

         if Sys_data_exi == 1
          app.Print_Batt_Ftime.Enable = 1;
          app.CustomtimerangePanel.Enable = 1;
          Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
          Sys_Row_num = length(Sys_data); 
          Sys_MaxFT = Sys_data(Sys_Row_num,2); % Reading the last flight time written
          Sys_StartFT = Sys_data(1,2); % Reading the first time written, it should be 1
          Sys_BattVolt = Sys_data(:,1); % Getting the battery voltage vector
          app.FT_DB.Value = Sys_MaxFT + " s";

          app.StartTime_Slider.Limits = [Sys_StartFT,Sys_MaxFT];
          app.EndTime_Slider.Limits = [Sys_StartFT,Sys_MaxFT];
          app.StartTime_DB.Value = num2str(Sys_StartFT) + " s";
          app.EndTime_DB.Value = num2str(Sys_MaxFT) + " s";
         end

         if Sys_data_exi == 1 && GNSS_data_exi == 1
          app.Print_Alt_Ftime.Enable = 1;  
         end

        end

        % Button pushed function: Print_map
        function Print_mapPushed(app, event)
         figure('Name','Track on map');
         geobasemap streets-dark;
         GNSS_data = importdata(fullfile(app.wrk_path, 'GNSS_data.txt'));
         GNSS_lat = GNSS_data(:,4);
         GNSS_lon = GNSS_data(:,5);
         geoplot(GNSS_lat,GNSS_lon,"Marker",'o',"MarkerSize",6,"Color",'r');
         app.DialogBox.Value = "Track on map printed"
         app.MB_list.Enable = 1; 
        end

        % Button pushed function: Print_globe
        function Print_globePushed(app, event)
         globe_figure = uifigure('Name','Track on globe');    
         globe = geoglobe(globe_figure,'Basemap','satellite');
         GNSS_data = importdata(fullfile(app.wrk_path, 'GNSS_data.txt'));
         GNSS_lat = GNSS_data(:,4);
         GNSS_lon = GNSS_data(:,5);
         GNSS_alt = GNSS_data(:,6);
         geoplot3(globe,GNSS_lat,GNSS_lon,GNSS_alt,"Marker",'o',"MarkerSize",6,"Color",'r')  
         app.DialogBox.Value = "Track on globe printed"
        end

        % Value changed function: MB_list
        function MB_listValueChanged(app, event)
                 
         switch app.MB_list.Value
          case char('Dark streets')
           geobasemap streets-dark; 

          case char('Streets')
           geobasemap streets; 

          case char('Color terrain')
           geobasemap colorterrain;  

          case char('Satellite')
           geobasemap satellite; 
    
          otherwise
         end     
        end

        % Callback function
        function Open_MPAButtonPushed(app, event)
         TuwaMPA;   
        end

        % Button pushed function: Print_Batt_Ftime
        function Print_Batt_FtimePushed(app, event)
         figure();
         Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
         Sys_BattVolt = Sys_data(:,1); % Getting the battery voltage vector
         Sys_Flight_Time = Sys_data(:,2);  % Getting the flight time vector
         plot(Sys_Flight_Time, Sys_BattVolt);
         grid on;
         title('Battery voltage - Flight time');
         xlabel('Flight time [s]');
         ylabel('Battery volatage [V]');
        end

        % Button pushed function: Print_Alt_Ftime
        function Print_Alt_FtimeButtonPushed(app, event)
         figure();

         % This instruction are executed in case of a "Full" time range is
         % selected
         if app.PlottimerangeSwitch.Value == "Full"
          Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
          Sys_Flight_Time = Sys_data(:,2);  % Getting the flight time vector
          GNSS_data = importdata(fullfile(app.wrk_path, 'GNSS_data.txt'));
          GNSS_alt = GNSS_data(:,6);  % Getting the GNSS flight altitude
          plot(Sys_Flight_Time, GNSS_alt);
          grid on;
          title('GNSS flight altitude - Flight time');
          xlabel('Flight time [s]');
          ylabel('GNSS altitude [m]');   
         end
         
         % This instruction are executed in case of a "Custom" time range is
         % selected
         if app.PlottimerangeSwitch.Value == "Custom"                   
          Start_row_index = round(app.StartTime_Slider.Value);
          End_row_index = round(app.EndTime_Slider.Value);
          Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
          Sys_Flight_Time = Sys_data(:,2);  % Getting the flight time vector
          GNSS_data = importdata(fullfile(app.wrk_path, 'GNSS_data.txt'));
          GNSS_alt = GNSS_data(:,6);  % Getting the GNSS flight altitude
          
          plot(Sys_Flight_Time(Start_row_index:End_row_index), GNSS_alt(Start_row_index:End_row_index));       
          grid on;
          title('GNSS flight altitude - Flight time');
          xlabel('Flight time [s]');
          ylabel('GNSS altitude [m]');   
         end
        end

        % Value changed function: StartTime_Slider
        function StartTime_SliderValueChanged(app, event)
         Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
         Sys_Row_num = length(Sys_data); 
         TimeRange_ST = app.StartTime_Slider.Value; % Getting the start time for the "time range selected" analysis
         app.EndTime_Slider.Limits = [round(TimeRange_ST), Sys_data(Sys_Row_num,2)]; % This to mantein the coherence beween the times selected on the two sliders       
         app.StartTime_DB.Value = num2str(round(TimeRange_ST)) + " s";
        end

        % Value changed function: EndTime_Slider
        function EndTime_SliderValueChanged(app, event)
         Sys_data = importdata(fullfile(app.wrk_path, 'Sys_data.txt'));
         Sys_Row_num = length(Sys_data); 
         TimeRange_ET = app.EndTime_Slider.Value; % Getting the end time for the "time range selected" analysis
         app.StartTime_Slider.Limits = [Sys_data(1,2),round(TimeRange_ET)]; % This to mantein the coherence beween the times selected on the two sliders  
         app.EndTime_DB.Value = num2str(round(TimeRange_ET)) + " s";
        end

        % Value changed function: PlottimerangeSwitch
        function PlottimerangeSwitchValueChanged(app, event)
         if app.PlottimerangeSwitch.Value == "Full"
          app.CustomtimerangePanel.BorderColor = "black"; % Highlighting the custom time range panel   
         end

         if app.PlottimerangeSwitch.Value == "Custom"
          app.CustomtimerangePanel.BorderColor = "red"; % Highlighting the custom time range panel   
         end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainTab = uifigure('Visible', 'off');
            app.MainTab.Name = 'MATLAB App';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
            app.CustomtimerangePanel = uipanel(app.MainTab);
            app.CustomtimerangePanel.Title = 'Custom time range';
            app.StartTime_Slider = uislider(app.CustomtimerangePanel);
            app.StartTime_Slider.Limits = [1 100];
            app.StartTime_Slider.ValueChangedFcn = createCallbackFcn(app, @StartTime_SliderValueChanged, true);
            app.StartTime_Slider.Value = 1;
            app.StarttimeinsecondsLabel = uilabel(app.CustomtimerangePanel);
            app.StarttimeinsecondsLabel.Text = 'Start time in seconds';
            app.EndTime_Slider = uislider(app.CustomtimerangePanel);
            app.EndTime_Slider.Limits = [1 100];
            app.EndTime_Slider.ValueChangedFcn = createCallbackFcn(app, @EndTime_SliderValueChanged, true);
            app.EndTime_Slider.Value = 1;
            app.EndtimeinsecondsLabel = uilabel(app.CustomtimerangePanel);
            app.EndtimeinsecondsLabel.Text = 'End time in seconds';
            app.StartTime_DB = uitextarea(app.CustomtimerangePanel);
            app.StarttimeLabel = uilabel(app.CustomtimerangePanel);
            app.StarttimeLabel.Text = 'Start time';
            app.EndtimeLabel = uilabel(app.CustomtimerangePanel);
            app.EndtimeLabel.Text = 'End time';
            app.EndTime_DB = uitextarea(app.CustomtimerangePanel);
            app.MissionprofileanalyzerPanel = uipanel(app.MainTab);
            app.MissionprofileanalyzerPanel.Title = 'Mission profile analyzer';
            app.Print_Batt_Ftime = uibutton(app.MissionprofileanalyzerPanel, 'push');
            app.Print_Batt_Ftime.ButtonPushedFcn = createCallbackFcn(app, @Print_Batt_FtimePushed, true);
            app.Print_Batt_Ftime.Text = 'Battery-time';
            app.Print_Alt_Ftime = uibutton(app.MissionprofileanalyzerPanel, 'push');
            app.Print_Alt_Ftime.ButtonPushedFcn = createCallbackFcn(app, @Print_Alt_FtimeButtonPushed, true);
            app.Print_Alt_Ftime.Text = 'Altitude-time';
            app.PlottimerangeSwitch = uiswitch(app.MissionprofileanalyzerPanel, 'slider');
            app.PlottimerangeSwitch.Items = {'Full', 'Custom'};
            app.PlottimerangeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlottimerangeSwitchValueChanged, true);
            app.PlottimerangeSwitch.Value = 'Full';
            app.PlottimerangeSwitchLabel = uilabel(app.MissionprofileanalyzerPanel);
            app.PlottimerangeSwitchLabel.Text = 'Plot time range';
            app.FlighttrackplotPanel = uipanel(app.MainTab);
            app.FlighttrackplotPanel.Title = 'Flight track plot';
            app.Print_map = uibutton(app.FlighttrackplotPanel, 'push');
            app.Print_map.ButtonPushedFcn = createCallbackFcn(app, @Print_mapPushed, true);
            app.Print_map.Text = 'Track on map';
            app.Print_globe = uibutton(app.FlighttrackplotPanel, 'push');
            app.Print_globe.ButtonPushedFcn = createCallbackFcn(app, @Print_globePushed, true);
            app.Print_globe.Text = 'Track on globe';
            app.MapbaseListBoxLabel = uilabel(app.FlighttrackplotPanel);
            app.MapbaseListBoxLabel.Text = 'Map base';
            app.MB_list = uilistbox(app.FlighttrackplotPanel);
            app.MB_list.Items = {'Dark streets', 'Streets', 'Color terrain', 'Satellite'};
            app.MB_list.ValueChangedFcn = createCallbackFcn(app, @MB_listValueChanged, true);
            app.MB_list.Value = 'Dark streets';
            app.ApplicationinformationPanel = uipanel(app.MainTab);
            app.ApplicationinformationPanel.Title = 'Application information';
            app.DialogboxTextAreaLabel = uilabel(app.ApplicationinformationPanel);
            app.DialogboxTextAreaLabel.Text = 'Dialog box';
            app.DialogBox = uitextarea(app.ApplicationinformationPanel);
            app.Label_2 = uilabel(app.ApplicationinformationPanel);
            app.Label_2.Text = 'General flight information';
            app.MaximumspeedLabel = uilabel(app.ApplicationinformationPanel);
            app.MaximumspeedLabel.Text = 'Maximum speed';
            app.MaxSpeed_DB = uitextarea(app.ApplicationinformationPanel);
            app.MaximumspeedLabel_2 = uilabel(app.ApplicationinformationPanel);
            app.MaximumspeedLabel_2.Text = 'Maximum altitude';
            app.MaxAlt_DB = uitextarea(app.ApplicationinformationPanel);
            app.MaximumspeedLabel_3 = uilabel(app.ApplicationinformationPanel);
            app.MaximumspeedLabel_3.Text = 'Flight time';
            app.FT_DB = uitextarea(app.ApplicationinformationPanel);
            app.Label_1 = uilabel(app.ApplicationinformationPanel);
            app.Label_1.Text = 'Loaded files';
            app.Sys_file = uicheckbox(app.ApplicationinformationPanel);
            app.Sys_file.Text = 'Syst. data file';
            app.IMU_file = uicheckbox(app.ApplicationinformationPanel);
            app.IMU_file.Text = 'IMU data file';
            app.ERR_file = uicheckbox(app.ApplicationinformationPanel);
            app.ERR_file.Text = 'ERR. data file';
            app.GNSS_file = uicheckbox(app.ApplicationinformationPanel);
            app.GNSS_file.Text = 'GNSS data file';
            app.Main = uipanel(app.MainTab);
            app.Main.Title = 'Main';
            app.Load_folder = uibutton(app.Main, 'push');
            app.Load_folder.ButtonPushedFcn = createCallbackFcn(app, @Load_folderPushed, true);
            app.Load_folder.Text = 'Open folder';
            app.Open_Plugin = uibutton(app.Main, 'push');
            app.Open_Plugin.Text = 'Plugin';
            app.Gen_Mix_prof = uibutton(app.Main, 'push');
            app.Gen_Mix_prof.Text = 'Mision profile analyzer';
            % Show the figure after all components are created
            app.MainTab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainTab)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainTab)
        end
    end
end